classdef UltrasoundWaveControl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        UltrasoundWaveControlLabel  matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        Panel_6                     matlab.ui.container.Panel
        UIAxes                      matlab.ui.control.UIAxes
        Panel_2                     matlab.ui.container.Panel
        WaveEnergyY2Label           matlab.ui.control.Label
        Panel_7                     matlab.ui.container.Panel
        EnergyIncidentRadioButton   matlab.ui.control.NumericEditField
        Panel_5                     matlab.ui.container.Panel
        FrequencyNumericEditField   matlab.ui.control.NumericEditField
        Panel_4                     matlab.ui.container.Panel
        WavelengthNumericEditField  matlab.ui.control.NumericEditField
        Panel_3                     matlab.ui.container.Panel
        AmplitudeNumericEditField   matlab.ui.control.NumericEditField
        FrequencyMHzKnob            matlab.ui.control.Knob
        FrequencyMHzKnobLabel       matlab.ui.control.Label
        WavelengthmmKnob            matlab.ui.control.Knob
        WavelengthLabel             matlab.ui.control.Label
        AmplitudeYKnob              matlab.ui.control.Knob
        AmplitudeYKnobLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AmplitudeYKnob
        function AmplitudeYKnobValueChanged(app, event)
            amplitude = app.AmplitudeYKnob.Value;
            frequency = app.FrequencyMHzKnob.Value;
            wavelength = app.WavelengthmmKnob.Value;
           
            app.AmplitudeNumericEditField.Value = amplitude;

            t = linspace(0, 1, 1000);  % Time vector
            waveform = amplitude * sin(2*pi*frequency*t / wavelength);
            
            % Calculate energy incident on the membrane
            energy = sum(waveform.^2);
            app.EnergyIncidentRadioButton.Value = energy;

            plot(app.UIAxes, t, waveform);
            hold(app.UIAxes, 'on');
            line(app.UIAxes, [0.5 0.5], [-amplitude amplitude], 'Color', 'r', 'LineWidth', 2);
            hold(app.UIAxes, 'off');
            
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude (Y)');
            title(app.UIAxes, 'Ultrasound Waveform');
        end

        % Value changed function: FrequencyMHzKnob
        function FrequencyMHzKnobValueChanged(app, event)
            amplitude = app.AmplitudeYKnob.Value;
            frequency = app.FrequencyMHzKnob.Value;
            wavelength = app.WavelengthmmKnob.Value;

            app.FrequencyNumericEditField.Value = frequency;
    
            t = linspace(0, 1, 1000);  % Time vector
            waveform = amplitude * sin(2*pi*frequency*t / wavelength);

            % Calculate energy incident on the membrane
            energy = sum(waveform.^2);
            app.EnergyIncidentRadioButton.Value = energy;

            plot(app.UIAxes, t, waveform);
            hold(app.UIAxes, 'on');
            line(app.UIAxes, [0.5 0.5], [-amplitude amplitude], 'Color', 'r', 'LineWidth', 2);
            hold(app.UIAxes, 'off');

            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude (Y)');
            title(app.UIAxes, 'Ultrasound Waveform');
        end

        % Value changed function: WavelengthmmKnob
        function WavelengthmmKnobValueChanged(app, event)
            amplitude = app.AmplitudeYKnob.Value;
            frequency = app.FrequencyMHzKnob.Value;
            wavelength = app.WavelengthmmKnob.Value;

            app.WavelengthNumericEditField.Value = wavelength;

            t = linspace(0, 1, 1000);  % Time vector
            waveform = amplitude * sin(2*pi*frequency*t / wavelength);
        
            % Calculate energy incident on the membrane
            energy = sum(waveform.^2);
            app.EnergyIncidentRadioButton.Value = energy;

            plot(app.UIAxes, t, waveform);
            hold(app.UIAxes, 'on');
            line(app.UIAxes, [0.5 0.5], [-amplitude amplitude], 'Color', 'r', 'LineWidth', 2);
            hold(app.UIAxes, 'off');
        
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude (Y)');
            title(app.UIAxes, 'Ultrasound Waveform');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel_2 = uipanel(app.Panel);
            app.AmplitudeYKnobLabel = uilabel(app.Panel_2);
            app.AmplitudeYKnobLabel.Text = 'Amplitude (Y)';
            app.AmplitudeYKnob = uiknob(app.Panel_2, 'continuous');
            app.AmplitudeYKnob.ValueChangedFcn = createCallbackFcn(app, @AmplitudeYKnobValueChanged, true);
            app.WavelengthLabel = uilabel(app.Panel_2);
            app.WavelengthLabel.Text = 'Wavelength (mm)';
            app.WavelengthmmKnob = uiknob(app.Panel_2, 'continuous');
            app.WavelengthmmKnob.Limits = [0 1.5];
            app.WavelengthmmKnob.ValueChangedFcn = createCallbackFcn(app, @WavelengthmmKnobValueChanged, true);
            app.FrequencyMHzKnobLabel = uilabel(app.Panel_2);
            app.FrequencyMHzKnobLabel.Text = 'Frequency (MHz)';
            app.FrequencyMHzKnob = uiknob(app.Panel_2, 'continuous');
            app.FrequencyMHzKnob.Limits = [0 25];
            app.FrequencyMHzKnob.MajorTicks = [0 5 10 15 20 25];
            app.FrequencyMHzKnob.ValueChangedFcn = createCallbackFcn(app, @FrequencyMHzKnobValueChanged, true);
            app.FrequencyMHzKnob.MinorTicks = [0 2.5 5 7.5 10 12.5 15 17.5 20 22.5 25];
            app.Panel_3 = uipanel(app.Panel_2);
            app.AmplitudeNumericEditField = uieditfield(app.Panel_3, 'numeric');
            app.AmplitudeNumericEditField.Editable = 'off';
            app.Panel_4 = uipanel(app.Panel_2);
            app.WavelengthNumericEditField = uieditfield(app.Panel_4, 'numeric');
            app.WavelengthNumericEditField.Editable = 'off';
            app.Panel_5 = uipanel(app.Panel_2);
            app.FrequencyNumericEditField = uieditfield(app.Panel_5, 'numeric');
            app.FrequencyNumericEditField.Editable = 'off';
            app.Panel_7 = uipanel(app.Panel_2);
            app.EnergyIncidentRadioButton = uieditfield(app.Panel_7, 'numeric');
            app.WaveEnergyY2Label = uilabel(app.Panel_2);
            app.WaveEnergyY2Label.Text = 'Wave Energy (Y^2)';
            app.Panel_6 = uipanel(app.Panel);
            app.UIAxes = uiaxes(app.Panel_6);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'Time (s)')
            ylabel(app.UIAxes, 'Amplitude (Y)')
            zlabel(app.UIAxes, 'Z')
            app.UltrasoundWaveControlLabel = uilabel(app.UIFigure);
            app.UltrasoundWaveControlLabel.Text = 'Ultrasound Wave Control';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UltrasoundWaveControl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end